classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        LeftPanel                matlab.ui.container.Panel
        RangominEditFieldLabel   matlab.ui.control.Label
        RangominEditField        matlab.ui.control.NumericEditField
        RangomaxEditFieldLabel   matlab.ui.control.Label
        RangomaxEditField        matlab.ui.control.NumericEditField
        DepuntosEditFieldLabel   matlab.ui.control.Label
        DepuntosEditField        matlab.ui.control.NumericEditField
        LimpiarButton            matlab.ui.control.Button
        BackButton               matlab.ui.control.Button
        TiempoporVectoresLabel   matlab.ui.control.Label
        TiempoporEscalaresLabel  matlab.ui.control.Label
        CalcularButton           matlab.ui.control.Button
        Label                    matlab.ui.control.Label
        Label_2                  matlab.ui.control.Label
        RightPanel               matlab.ui.container.Panel
        UIAxes                   matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LimpiarButton
        function LimpiarButtonPushed(app, event)
            clf
        end

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            min = get(app.RangominEditField, 'Value');
            max = get(app.RangomaxEditField, 'Value');
            p = get(app.DepuntosEditField, 'Value');
            figure(app.UIFigure)
            [time1,time2, a] = ejercicio3(min,max,p);
           
            app.Label.Text = num2str(time1);
            drawnow
            app.Label_2.Text = num2str(time2);
            drawnow
            
          hold on;
    syms y(x);
    y(x) = piecewise(-7<x<=5, -x, 5<x<7, 2.*x-10, x>7, x.^2);
        d = linspace(min, max, p);
     plot(d,y(d))
     
     syms h(x);
    h(x) = piecewise(x<3, 2, 5<x<20, log(x), x>23, exp(x));
     e = linspace(min, max, p);
     plot(e,h(e))
     
     syms g(x);
    g(x) = piecewise(x<-3, 2, -3<=x<=11, x./2, x>11, 3);
     f = linspace(min, max, p);
     plot(f,g(f))
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RangominEditFieldLabel = uilabel(app.LeftPanel);
            app.RangominEditFieldLabel.Text = 'Rango  min';
            app.RangominEditField = uieditfield(app.LeftPanel, 'numeric');
            app.RangomaxEditFieldLabel = uilabel(app.LeftPanel);
            app.RangomaxEditFieldLabel.Text = 'Rango max';
            app.RangomaxEditField = uieditfield(app.LeftPanel, 'numeric');
            app.DepuntosEditFieldLabel = uilabel(app.LeftPanel);
            app.DepuntosEditFieldLabel.Text = '# De puntos';
            app.DepuntosEditField = uieditfield(app.LeftPanel, 'numeric');
            app.LimpiarButton = uibutton(app.LeftPanel, 'push');
            app.LimpiarButton.ButtonPushedFcn = createCallbackFcn(app, @LimpiarButtonPushed, true);
            app.LimpiarButton.Text = 'Limpiar';
            app.BackButton = uibutton(app.LeftPanel, 'push');
            app.BackButton.Text = 'Back';
            app.TiempoporVectoresLabel = uilabel(app.LeftPanel);
            app.TiempoporVectoresLabel.Text = 'Tiempo por Vectores: ';
            app.TiempoporEscalaresLabel = uilabel(app.LeftPanel);
            app.TiempoporEscalaresLabel.Text = 'Tiempo por Escalares: ';
            app.CalcularButton = uibutton(app.LeftPanel, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.LeftPanel);
            app.Label_2.Text = '';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end